classdef simpleStroop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StartTestButton  matlab.ui.control.Button
        BlueButton       matlab.ui.control.Button
        RedButton        matlab.ui.control.Button
    end

    % Copyright 2023 The MathWorks, Inc.
    
    properties (Access = private)
        trial = 1; 
        stimuliandresults; % Table holding presented stimuliu as well as responses
    end
    
    methods (Access = private)
        function changeStimulus(app)
            if ~checkEnd(app)
                % TASK 2: Set the text of ColorLabel. The next color to
                % present is in 
                % app.stimuliandresults.trialSeqWord(app.trial). The
                % property which represents the text is called "Text"
                colorAbbrev = app.stimuliandresults.trialSeqColor(app.trial);
                app.ColorLabel.FontColor = lower(colorAbbrev(1));
                pause(0.1);
                app.ColorLabel.Visible = 'on';
                tic;
            else
                delete(app);
            end
        end
        
        function results = checkEnd(app)
            if app.stimuliandresults.responseColorIdx(end) ~= 0
                % End has been reached (c.f. MATLAB Grader example), 
                % Save everything ... 
                ppName = inputdlg("Filename to save: ");
                writetable(app.stimuliandresults, join([ppName{1}, '.csv'], '.'));
                results = true;
                return
            else
                % Another trial is due, so report this back
                results = false;
                return
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            % Basic definitions
            colorLabels = ["Red"; "Green"; "Blue"];
            numRounds = 5; 
            
            % Determine trial sequence
            numTrials = length(colorLabels) * numRounds;
            trialSeq = repmat(colorLabels, [numRounds, 1]);
            rng('shuffle');
            trialSeqWord = trialSeq(randperm(numTrials));
            trialSeqColor = trialSeq(randperm(numTrials));
            
            % Pre-allocate response variables
            responseColorIdx = zeros(numTrials, 1);
            responseTime = zeros(numTrials, 1);
            
            % Arrange everything in a table
            app.stimuliandresults = table((1:numTrials)', trialSeqWord, trialSeqColor, responseColorIdx, responseTime);

            % Get going            
            app.StartTestButton.Visible = 'off';
            app.trial = 1;
            changeStimulus(app);

        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            % Derive results, i.e. response time and button pressed
            app.stimuliandresults.responseTime(app.trial) = toc;
            app.stimuliandresults.responseColorIdx(app.trial) = "Red";
            
            % Prepare and initiate next trial
            app.ColorLabel.Visible = 'off';
            app.trial = app.trial + 1;
            changeStimulus(app);
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            % Derive results, i.e. response time and button pressed
            app.stimuliandresults.responseTime(app.trial) = toc;
            app.stimuliandresults.responseColorIdx(app.trial) = "Blue";
            
            % Prepare and initiate next trial
            app.ColorLabel.Visible = 'off';
            app.trial = app.trial + 1;
            changeStimulus(app);
            
            % TASK 3: Insert a Callback function for a press of the Green
            % button. Copy and adapt the code from other button callbacks
            % to implement the functionality.
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleStroop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end